classdef ImportDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportDataUIFigure          matlab.ui.Figure
        StatusTextAreaLabel         matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        ImportConfirmButton         matlab.ui.control.Button
        SelectFolderButton          matlab.ui.control.Button
        ImportOptionsPanel          matlab.ui.container.Panel
        NormalizeCheckBox           matlab.ui.control.CheckBox
        CosmicRayRemovalCheckBox    matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox  matlab.ui.control.CheckBox
        ConverttoRelativeWavenumbersincm1RamanShiftCheckBox  matlab.ui.control.CheckBox
        UIPreviewTable              matlab.ui.control.Table
        AppendtocurrentlyloadeddatasetCheckBox  matlab.ui.control.CheckBox
        CancelButton                matlab.ui.control.Button
        StartImportButton           matlab.ui.control.Button
        NoFolderSelectedLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main App
        ImportedData
        SelectedFolder
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.SelectedFolder = 0;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Start in working directory
            start_path = pwd;
            thisFolder = uigetdir(start_path);
            
            if thisFolder == 0
                return;
            else
                app.SelectedFolder = thisFolder;
                app.StartImportButton.Enable = true;
                app.NoFolderSelectedLabel.Text = thisFolder;
            end
        end

        % Button pushed function: ImportConfirmButton
        function ImportConfirmButtonPushed(app, event)
            loadImportedData(app.CallingApp, app.ImportedData);
            
            delete(app);
        end

        % Button pushed function: StartImportButton
        function StartImportButtonPushed(app, event)
            if (app.SelectedFolder ~= 0)
                app.ImportConfirmButton.Text = "Processing ...";
                
                % Start import
                data = importRaman(app.SelectedFolder, app);
                
                % Data has been imported
                % Create preview
                previewTable = table({data.name}', {data.nSpectra}');
                app.UIPreviewTable.Data = previewTable;
                
                app.ImportedData = data;
                                
                app.ImportConfirmButton.Text = "Confirm";
                app.ImportConfirmButton.Enable = true;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportDataUIFigure = uifigure('Visible', 'off');
            app.ImportDataUIFigure.Name = 'Import Data';
            app.StatusTextAreaLabel = uilabel(app.ImportDataUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ImportDataUIFigure);
            app.ImportConfirmButton = uibutton(app.ImportDataUIFigure, 'push');
            app.ImportConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfirmButtonPushed, true);
            app.ImportConfirmButton.Enable = 'off';
            app.ImportConfirmButton.Text = 'Confirm';
            app.SelectFolderButton = uibutton(app.ImportDataUIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ImportOptionsPanel = uipanel(app.ImportDataUIFigure);
            app.ImportOptionsPanel.Title = 'Import Options';
            app.NormalizeCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.NormalizeCheckBox.Enable = 'off';
            app.NormalizeCheckBox.Text = 'Normalize';
            app.NormalizeCheckBox.Value = true;
            app.CosmicRayRemovalCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.CosmicRayRemovalCheckBox.Enable = 'off';
            app.CosmicRayRemovalCheckBox.Text = 'Cosmic Ray Removal';
            app.BaselineCorrectionCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.BaselineCorrectionCheckBox.Enable = 'off';
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineCorrectionCheckBox.Value = true;
            app.ConverttoRelativeWavenumbersincm1RamanShiftCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.ConverttoRelativeWavenumbersincm1RamanShiftCheckBox.Enable = 'off';
            app.ConverttoRelativeWavenumbersincm1RamanShiftCheckBox.Text = 'Convert to Relative Wavenumbers in cm-1 (Raman Shift)';
            app.ConverttoRelativeWavenumbersincm1RamanShiftCheckBox.Value = true;
            app.UIPreviewTable = uitable(app.ImportDataUIFigure);
            app.UIPreviewTable.ColumnName = {'Name'; 'N'};
            app.UIPreviewTable.RowName = {};
            app.AppendtocurrentlyloadeddatasetCheckBox = uicheckbox(app.ImportDataUIFigure);
            app.AppendtocurrentlyloadeddatasetCheckBox.Text = 'Append to currently loaded data set';
            app.CancelButton = uibutton(app.ImportDataUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.StartImportButton = uibutton(app.ImportDataUIFigure, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Enable = 'off';
            app.StartImportButton.Text = 'Start Import';
            app.NoFolderSelectedLabel = uilabel(app.ImportDataUIFigure);
            app.NoFolderSelectedLabel.Text = 'No Folder Selected...';
            % Show the figure after all components are created
            app.ImportDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportDataUIFigure)
        end
    end
end