classdef BgSubApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        UpdatePreviewButton             matlab.ui.control.Button
        SubtractionMethodSettingsLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        ResettoDefaultsButton           matlab.ui.control.Button
        SubtractionMethodDropDown       matlab.ui.control.DropDown
        SubtractionMethodDropDownLabel  matlab.ui.control.Label
        SubtractonPreviewLabel          matlab.ui.control.Label
        PreviewSelectionLabel           matlab.ui.control.Label
        PreviewSelectionTree            matlab.ui.container.CheckBoxTree
        TabGroup                        matlab.ui.container.TabGroup
        ConstantTab                     matlab.ui.container.Tab
        bLabelbf                        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EditFieldLocMinEnd              matlab.ui.control.NumericEditField
        EditFieldLocMinStart            matlab.ui.control.NumericEditField
        LocalMinimumButton              matlab.ui.control.RadioButton
        GlobalMinimumButton             matlab.ui.control.RadioButton
        RegressionTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        QuantileValueEditField          matlab.ui.control.NumericEditField
        QuantileValueEditFieldLabel     matlab.ui.control.Label
        EstimationMethodDropDown        matlab.ui.control.DropDown
        EstimationMethodDropDownLabel   matlab.ui.control.Label
        RegressionMethodDropDown        matlab.ui.control.DropDown
        RegressionMethodDropDownLabel   matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        bLabelbf_2                      matlab.ui.control.Label
        PolynomialTab                   matlab.ui.container.Tab
        PLSTab                          matlab.ui.container.Tab
        bLabelbf_3                      matlab.ui.control.Label
        PreviewUIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Description
        DataObj % Description
    end
    
    methods (Access = public)
        
        function updateTree(app)
            %UPDATETREE Updates preview tree
            
            tree = app.PreviewSelectionTree;
            
            % Clear Tree
            a = tree.Children;
            a.delete;
            
            for i = 1:numel(app.DataObj)
                data = app.DataObj(i);
                datanode = uitreenode(tree, ...
                    "Text", data.DisplayName, ...  % Set Tree Text Label
                    "NodeData", data);     % Set Handle to DataContainer
            end
        end
        
        function updatePreview(app)
            % Update Preview
            
            % Get previewed object
            dataObj = getSelectedPreviewObj(app);
            if isempty(dataObj)
                return
            end
            
            original = dataObj.Data;
            preview = copy(original);
            
            % What baseline correction method?
            method = app.SubtractionMethodDropDown.Value;
            options = getMethodOptions(app, method);
            %params = namedargs2cell(options);
            
            switch method
                case "constant"
                    preview.removeBaseline(method, options);
                case "builtin"
                    preview.removeBaseline(method, options);
                case "airPLS"
                    preview.removeBaseline(method, options);
                otherwise
                    return
            end
            
            % Get axes handles
            ax = app.PreviewUIAxes;
            cla(ax);
            
            % Hold for multiple data
            %hold(ax, 'on');
            
            % Plot original
            yyaxis(ax, 'left');
            xdatori = original.Graph;
            ydatori = original.FlatDataArray;
            
            plot(ax, xdatori, ydatori);
            
            % Plot baseline-corrected preview
            yyaxis(ax, 'right');
            xdatnew = preview.Graph;
            ydatnew = preview.FlatDataArray;
            
            plot(ax, xdatnew, ydatnew);
            
            
            % Release hold
            %hold(ax, 'off');
                
        end
        
        function data = getSelectedPreviewObj(app)
            
            tree = app.PreviewSelectionTree;
            
            data = [];
            
            if isempty(tree.Children)
                % Nothing to be previewed, data tree is empty
                return
            end
            
            node = tree.SelectedNodes;
            
            if numel(node) > 1
                % Can only preview one data object
                return
            end
            
            data = node.NodeData;
        end
        
        function options = getMethodOptions(app, method)
            % GETMETHODOPTIONS Get parameters of selected method
            
            switch method
                case "constant"
                    options = [];
                case "builtin"
                    options.WindowSize = app.WindowSizeEditField.Value;
                    options.StepSize = app.StepSizeEditField.Value;
                    options.RegressionMethod = app.RegressionMethodDropDown.Value;
                    options.EstimationMethod = app.EstimationMethodDropDown.Value;
                    options.QuantileValue = app.QuantileValueEditField.Value;
                case "airPLS"
                    options = [];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, options)
            % STARTUPFCN
            
            arguments
                app BgSubApp;
                mainapp ramatgui;
                options.DataObjects DataContainer = []; % Provided DataObjects for baseline subtractions
            end
            
            % Set caller (main) app
            app.MainApp = mainapp;
            
            if isempty(options.DataObjects)
                % No Data provided, retrieve from selection
                data = vertcat( app.DataMgrTree.SelectedNodes.NodeData );
            else
                data = options.DataObjects;
            end
            
            % Store data object handles
            app.DataObj = data;
            
            % Update Tree
            app.updateTree();
            
        end

        % Value changed function: SubtractionMethodDropDown
        function SubtractionMethodDropDownValueChanged(app, event)
            value = app.SubtractionMethodDropDown.Value;
            
            updatePreview(app);
        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            updatePreview(app);
        end

        % Selection changed function: PreviewSelectionTree
        function PreviewSelectionTreeSelectionChanged(app, event)
            selectedNodes = app.PreviewSelectionTree.SelectedNodes;
            
            updatePreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PreviewUIAxes = uiaxes(app.GridLayout);
            title(app.PreviewUIAxes, 'Title')
            xlabel(app.PreviewUIAxes, 'X')
            ylabel(app.PreviewUIAxes, 'Y')
            zlabel(app.PreviewUIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConstantTab = uitab(app.TabGroup);
            app.ConstantTab.Title = 'Constant';
            app.ButtonGroup = uibuttongroup(app.ConstantTab);
            app.GlobalMinimumButton = uiradiobutton(app.ButtonGroup);
            app.GlobalMinimumButton.Text = 'Global Minimum';
            app.GlobalMinimumButton.Value = true;
            app.LocalMinimumButton = uiradiobutton(app.ButtonGroup);
            app.LocalMinimumButton.Text = 'Local Minimum:';
            app.EditFieldLocMinStart = uieditfield(app.ButtonGroup, 'numeric');
            app.EditFieldLocMinEnd = uieditfield(app.ButtonGroup, 'numeric');
            app.bLabelbf = uilabel(app.ConstantTab);
            app.bLabelbf.Interpreter = 'html';
            app.bLabelbf.Text = {'<p><b>Constant Value Subtraction</b></p>'; '<p>Subtracts a constant value from entire spectrum</p>'};
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.GridLayout3 = uigridlayout(app.RegressionTab);
            app.bLabelbf_2 = uilabel(app.GridLayout3);
            app.bLabelbf_2.Interpreter = 'html';
            app.bLabelbf_2.Text = {'<p><b>MATLAB Built-In Regression</b></p>'; '<p>...</p>'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.WindowSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WindowSizeEditField.Limits = [0 Inf];
            app.WindowSizeEditField.Value = 200;
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.StepSizeEditField.Value = 200;
            app.RegressionMethodDropDownLabel = uilabel(app.GridLayout4);
            app.RegressionMethodDropDownLabel.Text = 'Regression Method';
            app.RegressionMethodDropDown = uidropdown(app.GridLayout4);
            app.RegressionMethodDropDown.Items = {'Piecewise Cubic', 'Linear', 'Spline'};
            app.RegressionMethodDropDown.ItemsData = {'pchip', 'linear', 'spline'};
            app.RegressionMethodDropDown.Value = 'pchip';
            app.EstimationMethodDropDownLabel = uilabel(app.GridLayout4);
            app.EstimationMethodDropDownLabel.Text = 'Estimation Method';
            app.EstimationMethodDropDown = uidropdown(app.GridLayout4);
            app.EstimationMethodDropDown.Items = {'Quantile', 'Expectation-Maximization'};
            app.EstimationMethodDropDown.ItemsData = {'quantile', 'em'};
            app.EstimationMethodDropDown.Value = 'quantile';
            app.QuantileValueEditFieldLabel = uilabel(app.GridLayout4);
            app.QuantileValueEditFieldLabel.Text = 'Quantile Value';
            app.QuantileValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.QuantileValueEditField.Limits = [0 1];
            app.QuantileValueEditField.Value = 0.1;
            app.PolynomialTab = uitab(app.TabGroup);
            app.PolynomialTab.Title = 'Polynomial';
            app.PLSTab = uitab(app.TabGroup);
            app.PLSTab.Title = 'PLS';
            app.bLabelbf_3 = uilabel(app.PLSTab);
            app.bLabelbf_3.Interpreter = 'html';
            app.bLabelbf_3.Text = {'<p><b>Penalised Least Squares</b></p>'; '<p>...</p>'};
            app.PreviewSelectionTree = uitree(app.GridLayout, 'checkbox');
            app.PreviewSelectionTree.SelectionChangedFcn = createCallbackFcn(app, @PreviewSelectionTreeSelectionChanged, true);
            app.PreviewSelectionLabel = uilabel(app.GridLayout);
            app.PreviewSelectionLabel.Text = 'Preview Selection:';
            app.SubtractonPreviewLabel = uilabel(app.GridLayout);
            app.SubtractonPreviewLabel.Text = 'Subtracton Preview:';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SubtractionMethodDropDownLabel = uilabel(app.GridLayout2);
            app.SubtractionMethodDropDownLabel.Text = 'Subtraction Method:';
            app.SubtractionMethodDropDown = uidropdown(app.GridLayout2);
            app.SubtractionMethodDropDown.Items = {'Constant', 'Regression', 'Polynomial', 'PLS'};
            app.SubtractionMethodDropDown.ItemsData = {'constant', 'builtin', 'none', 'airPLS'};
            app.SubtractionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtractionMethodDropDownValueChanged, true);
            app.SubtractionMethodDropDown.Value = 'constant';
            app.ResettoDefaultsButton = uibutton(app.GridLayout2, 'push');
            app.ResettoDefaultsButton.Text = 'Reset to Defaults';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.Text = 'Apply';
            app.SubtractionMethodSettingsLabel = uilabel(app.GridLayout);
            app.SubtractionMethodSettingsLabel.Text = 'Subtraction Method Settings:';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UpdatePreviewButton = uibutton(app.GridLayout5, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BgSubApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end