classdef SpectralPlotDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralPlotUIFigure      matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        GeneratePlotButton        matlab.ui.control.Button
        UISumTable                matlab.ui.control.Table
        GridLayout3               matlab.ui.container.GridLayout
        AutoupdateCheckBox        matlab.ui.control.CheckBox
        UpdatePreviewButton       matlab.ui.control.Button
        PlotOptionsPanel          matlab.ui.container.Panel
        cm1EditField              matlab.ui.control.NumericEditField
        cm1EditFieldLabel         matlab.ui.control.Label
        RangeEditField            matlab.ui.control.NumericEditField
        RangeEditFieldLabel       matlab.ui.control.Label
        StackDistanceMultiplier   matlab.ui.control.Slider
        StackDistanceSliderLabel  matlab.ui.control.Label
        CollateGroupsCheckBox     matlab.ui.control.CheckBox
        PlotTypeButtonGroup       matlab.ui.container.ButtonGroup
        OverlaidButton            matlab.ui.control.RadioButton
        StackedButton             matlab.ui.control.RadioButton
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        DataSet;
        PlotOptions = struct;
    end
    
    methods (Access = private)
        
        function updateSummaryTable(app)
%             resSummaryTable = table(app.groupingTable.GroupName, app.resTable.GroupCount);
%             
%             app.UISumTable.Data = resSummaryTable;
        end
        
        function updatePlotPreview(app)
            app.DataSet.plot( ...
                Axes=app.UIAxes, ...
                PlotStackDistance=app.PlotOptions.PlotStackDistance, ...
                PlotType=app.PlotOptions.PlotType);
        end
        
        function calculateStackDistance(app)
            nonIntersectDistance = max(max([app.resTable.yData{:}]));
            stackMultiplier = app.StackDistanceMultiplier.Value;
            
            app.PlotOptions.StackDistance = nonIntersectDistance * stackMultiplier;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            arguments
                app;
                data;
                options.CallingApp;
                options.GroupNames;
                options.GroupSizes;
                options.DataSizes;
                options.PlotType = 'Overlaid';
            end

            app.DataSet = data;
            
            app.PlotOptions.PlotType = options.PlotType;
            app.PlotOptions.PlotStackDistance = 1;            
            
            updatePlotPreview(app);
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            % plotSpectrum(app.resTable,app.groupingTable);
            plotSpectrum(app.groupingTable, app.resTable, app.PlotOptions);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Overlaid'
                    app.PlotOptions.Type = 'Overlaid';
                    app.StackDistanceMultiplier.Enable = false;
                case 'Stacked'
                    app.PlotOptions.Type = 'Stacked';
                    app.StackDistanceMultiplier.Enable = true;
            end
            
            updatePlotPreview(app);
        end

        % Value changed function: StackDistanceMultiplier
        function StackDistanceMultiplierValueChanged(app, event)
            calculateStackDistance(app);
            
            updatePlotPreview(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralPlotUIFigure = uifigure('Visible', 'off');
            app.SpectralPlotUIFigure.Name = 'Spectral Plot';
            app.GridLayout = uigridlayout(app.SpectralPlotUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotOptionsPanel = uipanel(app.GridLayout);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.PlotTypeButtonGroup = uibuttongroup(app.PlotOptionsPanel);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.StackedButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.OverlaidButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.OverlaidButton.Text = 'Overlaid';
            app.OverlaidButton.Value = true;
            app.CollateGroupsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.CollateGroupsCheckBox.Enable = 'off';
            app.CollateGroupsCheckBox.Text = 'Collate Groups';
            app.CollateGroupsCheckBox.Value = true;
            app.GeneratePlotButton = uibutton(app.GridLayout4, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.StackDistanceSliderLabel = uilabel(app.PlotOptionsPanel);
            app.StackDistanceSliderLabel.Text = 'Stack Distance';
            app.StackDistanceMultiplier = uislider(app.PlotOptionsPanel);
            app.StackDistanceMultiplier.Limits = [0 2];
            app.StackDistanceMultiplier.MajorTicks = [0 1 2];
            app.StackDistanceMultiplier.MajorTickLabels = {'min', 'default', 'max'};
            app.StackDistanceMultiplier.ValueChangedFcn = createCallbackFcn(app, @StackDistanceMultiplierValueChanged, true);
            app.StackDistanceMultiplier.MinorTicks = [];
            app.StackDistanceMultiplier.Value = 1;
            app.RangeEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.cm1EditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.cm1EditFieldLabel.Text = 'cm-1';
            app.cm1EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.AutoupdateCheckBox = uicheckbox(app.GridLayout3);
            app.AutoupdateCheckBox.Text = 'Auto-update';
            app.AutoupdateCheckBox.Value = true;
            app.UpdatePreviewButton = uibutton(app.GridLayout3, 'push');
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UpdatePreviewButton = uibutton(app.GridLayout3, 'push');
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.AutoupdateCheckBox = uicheckbox(app.GridLayout3);
            app.AutoupdateCheckBox.Text = 'Auto-update';
            app.AutoupdateCheckBox.Value = true;
            app.AutoupdateCheckBox = uicheckbox(app.GridLayout3);
            app.AutoupdateCheckBox.Text = 'Auto-update';
            app.AutoupdateCheckBox.Value = true;
            app.UpdatePreviewButton = uibutton(app.GridLayout3, 'push');
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.UISumTable = uitable(app.GridLayout);
            app.UISumTable.ColumnName = {'Name'; 'N'};
            app.UISumTable.RowName = {};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GeneratePlotButton = uibutton(app.GridLayout4, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.GeneratePlotButton = uibutton(app.GridLayout4, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            % Show the figure after all components are created
            app.SpectralPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralPlotDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralPlotUIFigure)
        end
    end
end