classdef ramatgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DVRamanToolUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        OpenRecentMenu                 matlab.ui.container.Menu
        emptyMenu                      matlab.ui.container.Menu
        ImportDataMenu                 matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        ExportDataMenu                 matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        SelectAllMenu                  matlab.ui.container.Menu
        DuplicateMenu                  matlab.ui.container.Menu
        RemoveMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        HelpMenu_2                     matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        SetDefaultButton               matlab.ui.control.Button
        ExportButton_2                 matlab.ui.control.Button
        CreateInstanceButton           matlab.ui.control.Button
        RemoveButton_2                 matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        DataItemsTable                 matlab.ui.control.Table
        UIPreviewAxes                  matlab.ui.control.UIAxes
        DataPreviewLabel               matlab.ui.control.Label
        AnalysisLabel                  matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        SubsetTree                     matlab.ui.container.Tree
        SubsetItemsTree                matlab.ui.container.CheckBoxTree
        Panel                          matlab.ui.container.Panel
        btnDeleteSmall                 matlab.ui.control.Button
        btnImportSmall                 matlab.ui.control.Button
        btnAddGroupSmall               matlab.ui.control.Button
        btnDuplicateSmall              matlab.ui.control.Button
        btnExportSmall                 matlab.ui.control.Button
        DataManagerLabel               matlab.ui.control.Label
        DataMgrTree                    matlab.ui.container.Tree
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        DataPanel                      matlab.ui.container.Panel
        NewGroupButton                 matlab.ui.control.Button
        RemoveButton_3                 matlab.ui.control.Button
        DuplicateButton                matlab.ui.control.Button
        ExportButton_3                 matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        WorkspacePanel                 matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        NewButton                      matlab.ui.control.Button
        btnSaveMore                    matlab.ui.control.Button
        SpectralCorrectionPanel        matlab.ui.container.Panel
        OverwriteCheckBox              matlab.ui.control.CheckBox
        TrimButton                     matlab.ui.control.Button
        CosmicRayRemovalButton         matlab.ui.control.Button
        RemoveBaselineButton           matlab.ui.control.Button
        NormalizeButton                matlab.ui.control.Button
        PeakAnalysisPanel              matlab.ui.container.Panel
        MinimumDistanceEditField       matlab.ui.control.NumericEditField
        MinimumDistanceEditFieldLabel  matlab.ui.control.Label
        AutoCheckBox_2                 matlab.ui.control.CheckBox
        AutoCheckBox                   matlab.ui.control.CheckBox
        MinimumHeightEditField         matlab.ui.control.NumericEditField
        MinimumHeightEditFieldLabel    matlab.ui.control.Label
        PlotPeaksButton                matlab.ui.control.Button
        ExtractPeakTableButton         matlab.ui.control.Button
        PlottingPanel                  matlab.ui.container.Panel
        QuickPlotButton                matlab.ui.control.Button
        SpectralAnalysisTab            matlab.ui.container.Tab
        PrincipalComponentAnalysisPanel  matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PCAStartRangeField             matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PCAEndRangeField               matlab.ui.control.NumericEditField
        cm1EditFieldLabel              matlab.ui.control.Label
        PCARangeButton                 matlab.ui.control.RadioButton
        PCAEntireSpectrumButton        matlab.ui.control.RadioButton
        PCAAnalyzeButton               matlab.ui.control.Button
        PlottingPanel_2                matlab.ui.container.Panel
        SpectralPlotButton_2           matlab.ui.control.Button
        AnalysisSubsetPanel            matlab.ui.container.Panel
        SubsetNameEditField            matlab.ui.control.EditField
        SubsetNameEditFieldLabel       matlab.ui.control.Label
        AreaScanTab                    matlab.ui.container.Tab
        FiltersPanel                   matlab.ui.container.Panel
        ManageFiltersButton            matlab.ui.control.Button
        OverviewPanel_2                matlab.ui.container.Panel
        AreaScanOverview               matlab.ui.control.TextArea
        PCATab                         matlab.ui.container.Tab
        OverviewPanel                  matlab.ui.container.Panel
        Tree                           matlab.ui.container.Tree
        PCADescription                 matlab.ui.control.TextArea
        DAnalysisPanel_2               matlab.ui.container.Panel
        PCAErrorEllipseCheckBox        matlab.ui.control.CheckBox
        OptionsButton                  matlab.ui.control.Button
        ConfidenceIntervalButtonGroup  matlab.ui.container.ButtonGroup
        RadioConfInt999                matlab.ui.control.RadioButton
        RadioConfInt990                matlab.ui.control.RadioButton
        RadioConfInt950                matlab.ui.control.RadioButton
        Scores2DScatterPlotButton      matlab.ui.control.Button
        DAnalysisPanel                 matlab.ui.container.Panel
        PCYSpinner                     matlab.ui.control.Spinner
        PCYSpinnerLabel                matlab.ui.control.Label
        PCXSpinner                     matlab.ui.control.Spinner
        PCXSpinnerLabel                matlab.ui.control.Label
        ScoresButtonY                  matlab.ui.control.Button
        ScoresButtonX                  matlab.ui.control.Button
        LoadingsButtonX                matlab.ui.control.Button
        LoadingsButtonY                matlab.ui.control.Button
        contxtSaveAs                   matlab.ui.container.ContextMenu
        SaveMenu_2                     matlab.ui.container.Menu
        SaveAsMenu_2                   matlab.ui.container.Menu
        contxtDataMgrTreeNode          matlab.ui.container.ContextMenu
        AddtoNewAnalysisMenu           matlab.ui.container.Menu
        AddtoMenu                      matlab.ui.container.Menu
        emptyMenuPlaceholder           matlab.ui.container.Menu
        contxtSubsetTreeNode           matlab.ui.container.ContextMenu
        AssigntoNewGroupMenu           matlab.ui.container.Menu
        AssigntoMenu                   matlab.ui.container.Menu
        RemoveMenu_2                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DataSet
        tblGroups
        SampleSelectionLogic
        HighlightedSampleIndices
        ImportDialog
        SpectralPlotDialog
        PCAResult = struct;     % Struct Containing PCA Results
        UIHighlighted = struct;
        
        rootdir % Root directory of executable
    end
    
    properties (Access = public)
        prj % The handle to the Project() Object
        OpenedDialogs = struct;
    end
    
    methods (Access = private)
        
        function updatePreviewPlot(app, data)
            % Update the preview pane
            
            % Check if we only have spectral data
            if isempty(data)
                % Nothing has been selected
                
                return;
            end
            
            if ~all( vertcat( data.dataType ) == "SpecData" )
                % Non-uniform data types have been selected
                
                return;
                
            end
            
            % Get axes handles
            ax = app.UIPreviewAxes;
            cla(ax);
            
            % Set-up Axes
            ax.PlotBoxAspectRatioMode = 'auto';
            ax.XLabel.String = 'Raman Shift [cm^-^1]';
            
            % Hold for multiple data
            hold(ax, 'on');
            
            for i = 1:numel(data)
                dat = data(i);
                
                xdat = dat.Graph;
                ydat = dat.DataPreview;
                
                plot(ax, xdat, ydat);
            end
            
            % Release hold
            hold(ax, 'off');
            
        end
        
        function loadDataSet(app)
            
            load_dataset(App=app);
            
        end
        
        function saveDataSet(app)
            
            save_dataset(App=app);
            
        end
        
        function results = getSelectedIndices(app,logicArray)
%             logicArray = app.SampleSelectionLogic;

            % Convert logic array to array of actual sample indices
            % e.g. [0 1 0 1] becomes [2 4]
            results = transpose(1:length(logicArray)) .* logicArray;
            results(results==0) = [];
        end
        
        function updateTables(app)
            %UPDATETABLES DEPRECATED
%             % Convert Data Structure to Table and Sort Columns
%             t = table(app.DataSet);
%   
%             g = app.tblGroups;
%             t = join(t,g,'LeftKeys','group','RightKeys','ID');
%             
%             % Create Drop Down List Strings
%             groupList = strings(height(t),1);
%             groupListAll = strings(height(g),1);
%             for i = 1:height(t)
%                 groupList(i) = sprintf("%u: %s",t.group(i),t.GroupName(i));
%             end
%             for i = 1:height(g)
%                 groupListAll(i) = sprintf("%u: %s",g.ID(i), g.GroupName(i));
%             end
%             t.GroupName = categorical(groupList,groupListAll);
%             
%             % Display Table
%             t = table(t.selected, t.name, t.xUnits, t.nSpectra, t.GroupName, 'VariableNames', ...
%                 {'selected','name','xUnits','nSpectra','GroupName'});
%             
%             app.UISamplesTable.Data = t;
%             app.UIGroupsTable.Data = g(:,[1 2 3]);
        end
        
        function createGroup(app)
            groups = table([1],true,"Group1",'VariableNames',{'ID','Selected','GroupName'});
            
            app.tblGroups = groups;
        end
        
        function spectralPlotStart(app)
            
            data = app.DataSet;
            
            %% HEREAFTER NON-OO CODE -----
            % Analyze Selected Groups
            % Get all sample data of selected groups
            t = table(data);
            g = app.tblGroups(app.tblGroups.Selected == true,:);
            resTable = innerjoin(t,g,'LeftKeys','group','RightKeys','ID');
            
            % Create averages if averaging of groups is selected
            % Yes this could be implemented better
            resTable = calcGroupedAverage(resTable);
            
            % Calculate number of samples per group and create a table
            % containing the name labels
            osum = @(x) sum(x);
            groupingTable = varfun(osum, resTable, 'GroupingVariables', 'group', ...
                'InputVariables','nSpectra');
            groupingTable = innerjoin(groupingTable,g,'LeftKeys','group','RightKeys','ID');
            
            app.SpectralPlotDialog = SpectralPlotDlg(app,resTable,groupingTable);
            
            
        end
        
        function removeSamples(app,indices)
            if isempty(indices)
                warning('No samples have been selected.');
                return
            else
                try
                    % Destroy objects
                    delete( app.DataSet(indices) );
                    
                    % Remove handles from object array
                    app.DataSet(indices) = [];
            
                    updateTables(app);
                catch
                    warning('Samples could not be removed.');
                end
            end
        end
        
        function loadingsPlotStart(app,pcax)
            wavenum = app.PCAResult.wavenum;
            loadings = app.PCAResult.coefs;
            plotLoadings(wavenum,loadings,pcax);
        end
        
        function assignGroupToSamples(app, group, samples)
            % Assign a group to multiple selected samples.
            
            [app.DataSet(samples).group] = deal(group);
            
            updateTables(app);
        end
        
        function newGroupID = addGroup(app)
            % Get list of current groups
            currentTable = app.tblGroups;    
            lastID = max(currentTable.ID);
            
            % Create a new group with a new ID
            newGroupID = lastID + 1;
            newName = sprintf("%s %d", "New Group", newGroupID);
            newRow = table(newGroupID, logical([1]), newName, 'VariableNames', {'ID','Selected','GroupName'});
            
            % Append to group table
            newTable = [currentTable; newRow];
            app.tblGroups = newTable;
            
            % Update GUI
            updateTables(app);
        end
        
        function status = deleteGroup(app, selectedGroupRows)
        % DELETEGROUP()
        % selectedGroupRows: states group row to be deleted, NOT ID.
            
            status = 0;
            
            if (size(selectedGroupRows) == 0)
                % Nothing has been selected, so nothing can be deleted.
                warning('No groups have been selected.');
                return
            end
            
            % Get list of current groups
            currentTable = app.tblGroups;
            selectedGroupIDs = currentTable.ID( selectedGroupRows );
            
            % Check if at least one group will remain after deletion
            nEntriesTable = height(currentTable);
            nDeletions = size(selectedGroupRows);
            
            if ((nEntriesTable - nDeletions(1)) < 1)
                warning('At least one group is needed for a dataset.');
                status = 1;
                return
            else
                
                % Check if any data is appended to the groups that are
                % about to be deleted.
                
                data = app.DataSet;
                currentGroupAssignments = vertcat( data.group );
                logicalMembers = ismember(currentGroupAssignments, selectedGroupIDs);
                                
                if (size(logicalMembers) > 0)
                    % The groups that are about to be deleted have data
                    % assigned to them
                    warning('The selected groups currently have assigned data to them. These samples have been assigned to a new group.');
                    
                    % Create a new group and assign data to it.
                    newGroupID = addGroup(app);
                    [data( logicalMembers ).group] = deal(newGroupID);
                    
                    % Update current table
                    currentTable = app.tblGroups;
                end
                
                try
                    currentTable( selectedGroupRows, : ) = [];
                    app.tblGroups = currentTable;
                catch
                    warning('Attempt to remove selected table rows failed.');
                    status = 2;
                end
                
            end
            
            % Update GUI
            updateTables(app);
        end
        

    end
    
    methods (Access = public)
        
        function loadImportedData(app, data)
            %LOADIMPORTEDDATA
            % app:      Main App
            % data:     nx1 DataContainer
            
            % Append Data to Project
            app.prj.append_data(data);
            
            % Update GUI Data Manager
            app.updatemgr();
        end
        
        function trimData(app, startX, endX, trimAll, overwrite)
            
            % Create object handles
            if (trimAll == true)
                objh = app.DataSet;
            else
                objh = app.DataSet( app.HighlightedSampleIndices );
            end
            
            % Call trimData method
            objh.trimData(startX, endX, overwrite);
       
            updateTables(app);
        end
        
        function results = getSelectedMeasurements(app)
            results = app.HighlightedSampleIndices;
        end
        
        function updatemgr(app)
            %UPDATEMGR
            %   Updates GUI Data Managers
            
            %% Update Data Manager
            tree = app.DataMgrTree;
            
            % Clear Tree
            a = tree.Children;
            a.delete;
            
            % Create node to store measurements
            measurementsnode = uitreenode(tree,"Text","Measurements");
            analysisresultsnode = uitreenode(tree, "Text", "Analysis Results");
            
            % Populate Measurements with Data Groups
            for i = 1:numel(app.prj.GroupSet)
                group = app.prj.GroupSet(i);
                
                groupnode = uitreenode(measurementsnode, ...
                    "Text", group.Name, ...
                    "Icon", "Folder_24.png");
                
                % Populate the group
                num_children = numel(group.Children);
                
                if num_children > 0
                    for j = 1:num_children
                        data = group.Children(j);
                        datanode = uitreenode(groupnode, ...
                            "Text", data.DisplayName, ...  % Set Tree Text Label
                            "NodeData", data, ...   % Set Handle to DataContainer
                            "ContextMenu", app.contxtDataMgrTreeNode);
                        
                        % Assign icon to node
                        switch (data.dataType)
                            case "SpecData"
                                % Spectral Data, evaluate the datasize
                                if data.DataSize > 1
                                    % Spectral Data of a scanned area
                                    datanode.Icon = "TDGraph_0.png";
                                else
                                    % Single Spectrum
                                    datanode.Icon = "TDGraph_2.png";
                                end
                                
                            case "TextData"
                                datanode.Icon = "TDText.png";
                                
                            case "ImageData"
                                datanode.Icon = "TDImage.png";
                                
                            otherwise
                                datanode.Icon = "default.png";
                        end
                        
                    end
                end
            end
            
            % Populate Analysis Results
            for i = 1:numel(app.prj.AnalysisResults)
                result = app.prj.AnalysisResults(i);
                
                resultnode = uitreenode(analysisresultsnode, ...
                    "Text", result.DisplayName, ...
                    "NodeData", result);
            end
            
            %% Update Analysis Subsets
            tree = app.SubsetTree;
            
            % Clear Tree
            a = tree.Children;
            a.delete;
            
            for i = 1:numel(app.prj.AnalysisSet)
                subset = app.prj.AnalysisSet(i);
                
                subsetnode = uitreenode(tree, ...
                    "Text", subset.DisplayName, ...
                    "NodeData", subset);
            end
            
            %% Update Analysis Items
            tree = app.SubsetItemsTree;
            
            % clear Tree
            a = tree.Children;
            a.delete;
            
            % Are there any subsets
            if (~isempty(app.prj.AnalysisSet) && ~isempty(app.prj.ActiveAnalysis))
                % TO-DO: make dependent property of prj, make sure one
                % analysis subset is selected!
                activeSubset = app.prj.ActiveAnalysis;
                
                % Add groups
                for g = 1:numel(activeSubset.GroupSet)
                    group = activeSubset.GroupSet(g);
                    
                    groupnode = uitreenode(tree, ...
                        "Text", group.DisplayName, ...
                        "NodeData", group);
                    
                    if ~isempty(group.Children)
                        % Group has DataContainer children
                        
                        for i = 1:numel(group.Children)
                            data = group.Children(i);
                            
                            datanode = uitreenode(groupnode, ...
                                "Text", data.DisplayName, ...
                                "NodeData", data, ...
                                "ContextMenu", app.contxtSubsetTreeNode);
                            
                            % Assign icon to node
                            switch (data.dataType)
                                case "SpecData"
                                    datanode.Icon = "TDGraph_2.png";
                                case "TextData"
                                    datanode.Icon = "TDText.png";
                                otherwise
                                    datanode.Icon = "default";
                            end
                            
                        end
                    end
                    
                end
                
                % Add uncategorised data
                % Removed
%                 groupnode = uitreenode(tree, ...
%                     "Text", "Ungrouped");
%                 
%                 for i = 1:numel(activeSubset.DataSet)
%                     data = activeSubset.DataSet(i);
%                     
%                     datanode = uitreenode(groupnode, ...
%                         "Text", data.DisplayName, ...
%                         "NodeData", data, ...
%                         "ContextMenu", app.contxtSubsetTreeNode);
%                 end
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initVar)
            %STARTUPFCN Initialises the GUI app
            
            % Unpack initialisation data from initVar argument
            app.prj = initVar.prj;  % Set working Project
            app.prj.Name = "New Project";
            app.rootdir = initVar.rootdir;
            
            % Set Window Title
            app.DVRamanToolUIFigure.Name = app.prj.Name;
            
            %loadDataSet(app);
            
            %Set struct
            app.UIHighlighted.samples = [0];
            app.UIHighlighted.groups = [0];
            
            % First update of managers
            app.updatemgr;
            
            
            % Set up sub windows
            app.OpenedDialogs.SpectralAreaDataViewer = SpecAreaDataViewer.empty();
        end

        % Callback function
        function UISamplesTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.HighlightedSampleIndices = indices;
            app.UIHighlighted.samples = indices;
            
            updatePreviewPlot(app,indices);
            
            disp(indices);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveDataSet(app);
        end

        % Button pushed function: Scores2DScatterPlotButton
        function Scores2DScatterPlotButtonPushed(app, event)
            % Retrieve PC Axes Spinner Values
            pcaxx = app.PCXSpinner.Value;
            pcaxy = app.PCYSpinner.Value;
            pcax = [pcaxx pcaxy];

            % Retrieve Results from PCAResult
            if isempty(app.prj.ActiveAnalysisResult)
                warning("No PCA Result found.");
                return
            end
            
            pcares = app.prj.ActiveAnalysisResult;
            
            % Retrieve Plotting Options
            showEllipsesBool = app.PCAErrorEllipseCheckBox.Value;
            
            % Plot PCA Scores
            pcares.scoresscatter(pcax, ErrorEllipse=showEllipsesBool);
        end

        % Callback function
        function UISamplesTableCellEdit(app, event)
            % Data in sample selection table has been changed by user
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            switch changedCol
                case 1 % Selection Changed
                    app.SampleSelectionLogic(changedRow) = newData;
                    app.DataSet(changedRow).selected = newData;
                case 5 % Group Changed
                    % Get Group ID, instead of full name
                    splittedString = strsplit(string(newData), ':');
                    newGroupID = str2num(splittedString(1));
                    
                    % Set Group ID
                    app.DataSet(changedRow).group = newGroupID;
            end
            
            
            idx = getSelectedIndices(app,app.SampleSelectionLogic);
            display(idx);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            loadDataSet(app);
        end

        % Callback function
        function SelectAllNoneButtonPushed(app, event)
            selection = app.SampleSelectionLogic;
            nSamples = length(selection);
            
            if (sum(selection) == length(selection))
                selection = zeros(nSamples,1);          % Table Set
                [app.DataSet.selected] = deal(false);   % Struct Set
            else
                selection = ones(nSamples,1);           % Table Set
                [app.DataSet.selected] = deal(true);    % Struct Set
            end
            
            app.SampleSelectionLogic = logical(selection); % Table Set
            
            updateTables(app);
        end

        % Callback function
        function ButtonGroupAddPushed(app, event)
            addGroup(app);
        end

        % Callback function
        function UIGroupsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            
            app.tblGroups{changedRow,changedCol} = newData;
                        
            updateTables(app);

        end

        % Button pushed function: btnImportSmall
        function btnImportSmallPushed(app, event)
            app.ImportDialog = ImportDialogApp(app);
        end

        % Button pushed function: PCAAnalyzeButton
        function PCAAnalyzeButtonPushed(app, event)
            % Create new PCA analysis
            
            % PCA Options from GUI
            range = [];
            
            if app.PCARangeButton.Value
                
                range = zeros(2, 1);
                range(1) = app.PCAStartRangeField.Value;
                range(2) = app.PCAEndRangeField.Value;
                
            end
            
            % Create PCA
            pcaresult = app.prj.create_pca(Range=range);
            
            % Add to analysis results
            app.prj.add_analysis_result(pcaresult);
            
            % Update managers
            app.updatemgr;

        end

        % Button pushed function: QuickPlotButton
        function QuickPlotButtonPushed(app, event)
            spectralPlotStart(app);
        end

        % Button pushed function: btnAddGroupSmall
        function btnAddGroupSmallPushed(app, event)
            % Add new group
            app.prj.add_group("New Group");
            
            % Update GUI Data Manager
            app.updatemgr();
        end

        % Button pushed function: LoadingsButtonX
        function LoadingsButtonXPushed(app, event)
            pcax = app.PCXSpinner.Value;
            loadingsPlotStart(app,pcax);
        end

        % Callback function
        function AssignButtonPushed(app, event)
            if (app.UIHighlighted.groups ~= 0 && min(app.UIHighlighted.samples) ~= 0)
                if size(app.UIHighlighted.groups) > 1
                    warning('You can only assign one group to the samples.');
                else
                    
                    group = app.UIHighlighted.groups;
                    samples = app.UIHighlighted.samples;
                    
                    try
                        assignGroupToSamples(app,group,samples)
                    catch ME
                        warning('Could not assign group to samples.');
                    end
                end
            end
        end

        % Callback function
        function UIGroupsTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.UIHighlighted.groups = indices;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.OpenedDialogs.AboutDlg = AboutDlg(app);
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
            app.OpenedDialogs = TrimToDlg(app);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            overwriteBool = app.OverwriteCheckBox.Value;
            
            % Get object handles
            objh = vertcat( app.DataMgrTree.SelectedNodes.NodeData );
            
            % Normalize Data
            objh.normalizeData( overwriteBool );
        end

        % Button pushed function: LoadingsButtonY
        function LoadingsButtonYPushed(app, event)
            pcay = app.PCYSpinner.Value;
            loadingsPlotStart(app,pcay);
        end

        % Value changed function: PCAErrorEllipseCheckBox
        function PCAErrorEllipseCheckBoxValueChanged(app, event)
            value = app.PCAErrorEllipseCheckBox.Value;
            
            if (value == true)
                app.RadioConfInt950.Enable = true;
                app.RadioConfInt990.Enable = true;
                app.RadioConfInt999.Enable = true;
            else
                app.RadioConfInt950.Enable = false;
                app.RadioConfInt990.Enable = false;
                app.RadioConfInt999.Enable = false;
            end
        end

        % Callback function
        function ButtonGroupDeletePushed(app, event)
            % Retrieve selected groups
            groupID = app.UIHighlighted.groups;
            
            % Delete groups
            try
                deleteGroup(app, groupID);
            catch
                warning('An error has occurred: Could not properly delete selected groups.');
            end
        end

        % Callback function: ImportButton, ImportDataMenu
        function ImportButtonPushed(app, event)
            app.ImportDialog = ImportDialogApp(app);
        end

        % Button pushed function: btnDuplicateSmall
        function btnDuplicateSmallButtonPushed(app, event)
            
        end

        % Button pushed function: btnSaveMore
        function btnSaveMoreButtonPushed(app, event)
            open(app.contxtSaveAs, app.btnSaveMore.Position(1), app.btnSaveMore.Position(2));
        end

        % Context menu opening function: contxtDataMgrTreeNode
        function contxtDataMgrTreeNodeContextMenuOpening(app, event)
            % This callback is called when the context menu of a data
            % manager tree node is opened.
            
            % It fills the Context Menu with appropriately with the
            % available analysis sets.
            
            % The called menu
            ctm = event.Source;
            ctm.Children.delete();
            
            mh = uimenu(ctm, ...
                'Text', 'Add to New Analysis', ...
                'Callback', @AddtoNewAnalysisMenuSelected);
            
            if numel(app.prj.AnalysisSet) > 0
                mh = uimenu(ctm, 'Text', 'Add to ...');
                
                for i = 1:numel(app.prj.AnalysisSet)
                    analysis = app.prj.AnalysisSet(i);
                    name = analysis.Name;
                    
                    menunode = uimenu(mh, ...
                        'Text', name, ...       % Name of analysis subset
                        'Callback', {@AddtoMenuSelected, analysis}); 
                end
            end
            
            mh = uimenu(ctm, ...
                'Text', 'Delete', ...
                'Callback', @DeleteMenuSelected);
            
            %% Calback nested functions
            
            function DeleteMenuSelected(varargin)
                % User has selected <DELETE> in contxtDataMgrTreeNode
            
                % TO-DO:
                % Get selected tree nodes
                h = app;
                h = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
                
                h.delete();
            end

            % Menu selected function: AddtoMenu
            function AddtoMenuSelected(varargin)
                % User has selected <ADD TO NEW ANALYSIS> in
                % contxtDataMgrTreeNode
                subsetHandle = varargin{3};
                
                % TO-DO:
                % - Check if selection is homogeneous
                
                % Get handles to selected data containers
                h = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
                
                h.addToSubset(subsetHandle);
                
                % Update GUI Managers
                app.updatemgr();
            end
            
            % Menu selected function: AddtoNewAnalysisMenu
            function AddtoNewAnalysisMenuSelected(varargin)
                % User has selected <ADD TO NEW ANALYSIS> in
                % contxtDataMgrTreeNode
                
                % TO-DO:
                % - Check if selection is homogeneous
                
                % Get handles to selected data containers
                h = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
                
                h.addToNewSubset();
                
                % Update GUI Managers
                app.updatemgr();
            end
        end

        % Selection changed function: SubsetTree
        function SubsetTreeSelectionChanged(app, event)
            selectedNodes = app.SubsetTree.SelectedNodes;
            
            % Set new active Analysis Set
            app.prj.ActiveAnalysis = selectedNodes.NodeData;
            
            % Update GUI Managers
            app.updatemgr();
        end

        % Context menu opening function: contxtSubsetTreeNode
        function contxtSubsetTreeNodeContextMenuOpening(app, event)
            % This callback is called when the context menu of a analysis
            % subset tree node is opened. It filld the context menu with
            % the available analysis subset groups.
            
            % The called menu
            ctm = event.Source;
            ctm.Children.delete();
            
            % The analysis subset whose items are currently listed
            analysis = app.prj.ActiveAnalysis;
            
            mh = uimenu(ctm, ...
                'Text', 'Assign to New Group', ...
                'Callback', @AssigntoNewGroupMenuSelected);
            
            if numel(analysis.GroupSet) > 0
                % The active analysis subset has groups
                mh = uimenu(ctm, 'Text', 'Assign to ...');
                
                for i = 1:numel(analysis.GroupSet)
                    group = analysis.GroupSet(i);
                    name = group.DisplayName;
                    
                    menunode = uimenu(mh, ...
                        'Text', name, ...       % Name of group
                        'Callback', {@AssigntoMenuSelected, group}); 
                end
            end
            
            mh = uimenu(ctm, ...
                'Text', 'Remove', ...
                'Callback', @RemoveMenuSelected);
            
            %% Calback nested functions
            
            function RemoveMenuSelected(varargin)
                % User has selected <REMOVE> in contxtSubsetItemTreeNode
            
                % TO-DO:
                % Get selected tree nodes
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                h.delete();
            end

            % Menu selected function: AddtoMenu
            function AssigntoMenuSelected(varargin)
                % User has selected <ASSIGN TO ...> in
                % contxtSubsetItemTreeNode
                newgroup = varargin{3};
                
                % Get handles to selected data containers
%                 nodes = 
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                analysis.move_data_to_group(h, newgroup);
                
                % Update GUI Managers
                app.updatemgr();
            end
            
            % Menu selected function: AddtoNewAnalysisMenu
            function AssigntoNewGroupMenuSelected(varargin)
                % User has selected <ADD TO NEW ANALYSIS> in
                % contxtSubsetItemTreeNode
                newgroup = analysis.add_group();
                
                % Get handles to selected data containers
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                analysis.move_data_to_group(h, newgroup);
                
                % Update GUI Managers
                app.updatemgr();
            end            
        end

        % Node text changed function: SubsetItemsTree
        function SubsetItemsTreeNodeTextChanged(app, event)
            % The changed object (DataContainer or AnalysisGroup)
            obj = event.Node.NodeData;
            
            % Set new name
            newname = event.Text;
            obj.Name = newname;
            
        end

        % Selection changed function: DataMgrTree
        function DataMgrTreeSelectionChanged(app, event)
            % User has changed selection in the DataMgrTree
            
            selectedNodes = app.DataMgrTree.SelectedNodes;
            
            % Get associated data containers
            nodeData = vertcat( selectedNodes.NodeData );
            
            % If node is an analysis result, set this to the current active
            % analysis result
            if (numel(nodeData) == 1)
                if (nodeData.dataType == "PCA")
                    
                    pcares = nodeData;
                    
                    % Set Active Analysis Result and prepare for analysis
                    app.prj.ActiveAnalysisResult = pcares;
                    app.TabGroup.SelectedTab = app.PCATab;
                    app.PCADescription.Value = pcares.Description;
                    
                end
                
                if (nodeData.dataType == "SpecData")
                    if (nodeData.DataSize > 1)
                        % Area Spectra Data Opened -> open filter window
                        

                        if isempty(app.OpenedDialogs.SpectralAreaDataViewer) || ~isvalid(app.OpenedDialogs.SpectralAreaDataViewer)
                            % Open new data viewer
                            app.OpenedDialogs.SpectralAreaDataViewer = SpecAreaDataViewer(app, nodeData);
                            
                        else
                            % Update data viewer
                            app.OpenedDialogs.SpectralAreaDataViewer.DataCon = nodeData;
                            app.OpenedDialogs.SpectralAreaDataViewer.update();
                            
                        end
                    end
                    
                end
                
            end
            
            % Pass data to updatePreviewPlot
            updatePreviewPlot(app, nodeData);
            
        end

        % Value changed function: PCXSpinner
        function PCXSpinnerValueChanged(app, event)

            % Update the scores scatter preview
            updateScoresScatterPlot(app);
            
        end

        % Value changed function: PCYSpinner
        function PCYSpinnerValueChanged(app, event)
            
            % Update the scores scatter preview
            updateScoresScatterPlot(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            load_dataset(App=app);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            save_dataset(App=app);
            
        end

        % Button pushed function: RemoveBaselineButton
        function RemoveBaselineButtonPushed(app, event)
            
            objh = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
            
            BgSubApp(app, DataObj=objh);
            
        end

        % Button pushed function: ManageFiltersButton
        function ManageFiltersButtonPushed(app, event)
            % Open Filter Manager
            
            %fmgr = FilterManager()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DVRamanToolUIFigure = uifigure('Visible', 'off');
            app.DVRamanToolUIFigure.Name = 'DV Raman Tool';
            app.FileMenu = uimenu(app.DVRamanToolUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Text = 'Open...';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Text = 'Open Recent';
            app.emptyMenu = uimenu(app.OpenRecentMenu);
            app.emptyMenu.Text = '*empty*';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportDataMenu.Text = 'Import Data...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Text = 'Export Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.DVRamanToolUIFigure);
            app.EditMenu.Text = 'Edit';
            app.SelectAllMenu = uimenu(app.EditMenu);
            app.SelectAllMenu.Text = 'Select All';
            app.DuplicateMenu = uimenu(app.EditMenu);
            app.DuplicateMenu.Text = 'Duplicate';
            app.RemoveMenu = uimenu(app.EditMenu);
            app.RemoveMenu.Text = 'Remove';
            app.HelpMenu = uimenu(app.DVRamanToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.DVRamanToolUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.PlottingPanel = uipanel(app.GeneralTab);
            app.PlottingPanel.Title = 'Plotting';
            app.QuickPlotButton = uibutton(app.PlottingPanel, 'push');
            app.QuickPlotButton.ButtonPushedFcn = createCallbackFcn(app, @QuickPlotButtonPushed, true);
            app.QuickPlotButton.Text = 'Quick Plot...';
            app.PeakAnalysisPanel = uipanel(app.GeneralTab);
            app.PeakAnalysisPanel.Title = 'Peak Analysis';
            app.ExtractPeakTableButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.ExtractPeakTableButton.Text = 'Extract Peak Table';
            app.PlotPeaksButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.PlotPeaksButton.Text = 'Plot Peaks';
            app.MinimumHeightEditFieldLabel = uilabel(app.PeakAnalysisPanel);
            app.MinimumHeightEditFieldLabel.Text = 'Minimum Height';
            app.MinimumHeightEditField = uieditfield(app.PeakAnalysisPanel, 'numeric');
            app.AutoCheckBox = uicheckbox(app.PeakAnalysisPanel);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox_2 = uicheckbox(app.PeakAnalysisPanel);
            app.AutoCheckBox_2.Text = 'Auto';
            app.MinimumDistanceEditFieldLabel = uilabel(app.PeakAnalysisPanel);
            app.MinimumDistanceEditFieldLabel.Text = 'Minimum Distance';
            app.MinimumDistanceEditField = uieditfield(app.PeakAnalysisPanel, 'numeric');
            app.SpectralCorrectionPanel = uipanel(app.GeneralTab);
            app.SpectralCorrectionPanel.Title = 'Spectral Correction';
            app.NormalizeButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize...';
            app.RemoveBaselineButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.RemoveBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBaselineButtonPushed, true);
            app.RemoveBaselineButton.Text = 'Remove Baseline...';
            app.CosmicRayRemovalButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.CosmicRayRemovalButton.Enable = 'off';
            app.CosmicRayRemovalButton.Text = 'Cosmic Ray Removal';
            app.TrimButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Text = 'Trim...';
            app.OverwriteCheckBox = uicheckbox(app.SpectralCorrectionPanel);
            app.OverwriteCheckBox.Text = 'Overwrite';
            app.WorkspacePanel = uipanel(app.GeneralTab);
            app.WorkspacePanel.Title = 'Workspace';
            app.btnSaveMore = uibutton(app.WorkspacePanel, 'push');
            app.btnSaveMore.ButtonPushedFcn = createCallbackFcn(app, @btnSaveMoreButtonPushed, true);
            app.btnSaveMore.Text = {' '; '▼'};
            app.NewButton = uibutton(app.WorkspacePanel, 'push');
            app.NewButton.Icon = 'New_24.png';
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.WorkspacePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'Folder_24.png';
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.WorkspacePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'Save_24.png';
            app.SaveButton.Text = 'Save';
            app.DataPanel = uipanel(app.GeneralTab);
            app.DataPanel.Title = 'Data';
            app.ImportButton = uibutton(app.DataPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = 'import_24.png';
            app.ImportButton.Text = 'Import';
            app.ExportButton_3 = uibutton(app.DataPanel, 'push');
            app.ExportButton_3.Icon = 'export.png';
            app.ExportButton_3.Text = 'Export';
            app.DuplicateButton = uibutton(app.DataPanel, 'push');
            app.DuplicateButton.Icon = 'Duplicate_16.png';
            app.DuplicateButton.Text = 'Duplicate';
            app.RemoveButton_3 = uibutton(app.DataPanel, 'push');
            app.RemoveButton_3.Icon = 'Delete_16.png';
            app.RemoveButton_3.Text = 'Remove';
            app.NewGroupButton = uibutton(app.DataPanel, 'push');
            app.NewGroupButton.Icon = 'plus-12.png';
            app.NewGroupButton.Text = 'New Group';
            app.SpectralAnalysisTab = uitab(app.TabGroup);
            app.SpectralAnalysisTab.Title = 'Spectral Analysis';
            app.AnalysisSubsetPanel = uipanel(app.SpectralAnalysisTab);
            app.AnalysisSubsetPanel.Title = 'Analysis Subset';
            app.SubsetNameEditFieldLabel = uilabel(app.AnalysisSubsetPanel);
            app.SubsetNameEditFieldLabel.Text = 'Subset Name';
            app.SubsetNameEditField = uieditfield(app.AnalysisSubsetPanel, 'text');
            app.PlottingPanel_2 = uipanel(app.SpectralAnalysisTab);
            app.PlottingPanel_2.Title = 'Plotting';
            app.SpectralPlotButton_2 = uibutton(app.PlottingPanel_2, 'push');
            app.SpectralPlotButton_2.Text = 'Spectral Plot...';
            app.PrincipalComponentAnalysisPanel = uipanel(app.SpectralAnalysisTab);
            app.PrincipalComponentAnalysisPanel.Title = 'Principal Component Analysis';
            app.PCAAnalyzeButton = uibutton(app.PrincipalComponentAnalysisPanel, 'push');
            app.PCAAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @PCAAnalyzeButtonPushed, true);
            app.PCAAnalyzeButton.Text = 'Create new PCA';
            app.ButtonGroup = uibuttongroup(app.PrincipalComponentAnalysisPanel);
            app.PCAEntireSpectrumButton = uiradiobutton(app.ButtonGroup);
            app.PCAEntireSpectrumButton.Text = 'Entire Spectrum';
            app.PCAEntireSpectrumButton.Value = true;
            app.PCARangeButton = uiradiobutton(app.ButtonGroup);
            app.PCARangeButton.Text = 'Range:';
            app.cm1EditFieldLabel = uilabel(app.ButtonGroup);
            app.cm1EditFieldLabel.Text = 'cm-1';
            app.PCAEndRangeField = uieditfield(app.ButtonGroup, 'numeric');
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '-';
            app.PCAStartRangeField = uieditfield(app.ButtonGroup, 'numeric');
            app.AreaScanTab = uitab(app.TabGroup);
            app.AreaScanTab.Title = 'Area Scan';
            app.OverviewPanel_2 = uipanel(app.AreaScanTab);
            app.OverviewPanel_2.Title = 'Overview';
            app.AreaScanOverview = uitextarea(app.OverviewPanel_2);
            app.AreaScanOverview.Editable = 'off';
            app.FiltersPanel = uipanel(app.AreaScanTab);
            app.FiltersPanel.Title = 'Filters';
            app.ManageFiltersButton = uibutton(app.FiltersPanel, 'push');
            app.ManageFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ManageFiltersButtonPushed, true);
            app.ManageFiltersButton.Icon = 'filter_16.png';
            app.ManageFiltersButton.Text = 'Manage Filters';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.DAnalysisPanel = uipanel(app.PCATab);
            app.DAnalysisPanel.Title = '1-D Analysis';
            app.LoadingsButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonY.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonYPushed, true);
            app.LoadingsButtonY.Text = 'Loadings';
            app.LoadingsButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonX.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonXPushed, true);
            app.LoadingsButtonX.Text = 'Loadings';
            app.ScoresButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX.Text = 'Scores';
            app.ScoresButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonY.Text = 'Scores';
            app.PCXSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCXSpinnerLabel.Text = 'PC X';
            app.PCXSpinner = uispinner(app.DAnalysisPanel);
            app.PCXSpinner.Limits = [1 Inf];
            app.PCXSpinner.ValueChangedFcn = createCallbackFcn(app, @PCXSpinnerValueChanged, true);
            app.PCXSpinner.Value = 1;
            app.PCYSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCYSpinnerLabel.Text = 'PC Y';
            app.PCYSpinner = uispinner(app.DAnalysisPanel);
            app.PCYSpinner.Limits = [1 Inf];
            app.PCYSpinner.ValueChangedFcn = createCallbackFcn(app, @PCYSpinnerValueChanged, true);
            app.PCYSpinner.Value = 2;
            app.DAnalysisPanel_2 = uipanel(app.PCATab);
            app.DAnalysisPanel_2.Title = '2-D Analysis';
            app.Scores2DScatterPlotButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.Scores2DScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @Scores2DScatterPlotButtonPushed, true);
            app.Scores2DScatterPlotButton.Text = 'Scores 2D Scatter Plot';
            app.ConfidenceIntervalButtonGroup = uibuttongroup(app.DAnalysisPanel_2);
            app.ConfidenceIntervalButtonGroup.Title = 'Confidence Interval:';
            app.RadioConfInt950 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt950.Enable = 'off';
            app.RadioConfInt950.Text = '95%';
            app.RadioConfInt950.Value = true;
            app.RadioConfInt990 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt990.Enable = 'off';
            app.RadioConfInt990.Text = '99%';
            app.RadioConfInt999 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt999.Enable = 'off';
            app.RadioConfInt999.Text = '99.9%';
            app.OptionsButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.OptionsButton.Text = 'Options...';
            app.PCAErrorEllipseCheckBox = uicheckbox(app.DAnalysisPanel_2);
            app.PCAErrorEllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PCAErrorEllipseCheckBoxValueChanged, true);
            app.PCAErrorEllipseCheckBox.Text = 'Show Confidence Ellipses';
            app.OverviewPanel = uipanel(app.PCATab);
            app.OverviewPanel.Title = 'Overview';
            app.PCADescription = uitextarea(app.OverviewPanel);
            app.PCADescription.Editable = 'off';
            app.Tree = uitree(app.OverviewPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DataMgrTree = uitree(app.GridLayout2);
            app.DataMgrTree.Multiselect = 'on';
            app.DataMgrTree.SelectionChangedFcn = createCallbackFcn(app, @DataMgrTreeSelectionChanged, true);
            app.DataMgrTree.Editable = 'on';
            app.DataManagerLabel = uilabel(app.GridLayout2);
            app.DataManagerLabel.Text = 'Data Manager';
            app.Panel = uipanel(app.GridLayout2);
            app.btnExportSmall = uibutton(app.Panel, 'push');
            app.btnExportSmall.Icon = 'export_16.png';
            app.btnExportSmall.Text = '';
            app.btnDuplicateSmall = uibutton(app.Panel, 'push');
            app.btnDuplicateSmall.ButtonPushedFcn = createCallbackFcn(app, @btnDuplicateSmallButtonPushed, true);
            app.btnDuplicateSmall.Icon = 'Duplicate_16.png';
            app.btnDuplicateSmall.Text = '';
            app.btnAddGroupSmall = uibutton(app.Panel, 'push');
            app.btnAddGroupSmall.ButtonPushedFcn = createCallbackFcn(app, @btnAddGroupSmallPushed, true);
            app.btnAddGroupSmall.Icon = 'plus-12.png';
            app.btnAddGroupSmall.Text = '';
            app.btnImportSmall = uibutton(app.Panel, 'push');
            app.btnImportSmall.ButtonPushedFcn = createCallbackFcn(app, @btnImportSmallPushed, true);
            app.btnImportSmall.Icon = 'import_16.png';
            app.btnImportSmall.Text = '';
            app.btnDeleteSmall = uibutton(app.Panel, 'push');
            app.btnDeleteSmall.Icon = 'Delete_16.png';
            app.btnDeleteSmall.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SubsetItemsTree = uitree(app.GridLayout3, 'checkbox');
            app.SubsetItemsTree.NodeTextChangedFcn = createCallbackFcn(app, @SubsetItemsTreeNodeTextChanged, true);
            app.SubsetItemsTree.Editable = 'on';
            app.SubsetTree = uitree(app.GridLayout3);
            app.SubsetTree.SelectionChangedFcn = createCallbackFcn(app, @SubsetTreeSelectionChanged, true);
            app.AnalysisLabel = uilabel(app.GridLayout2);
            app.AnalysisLabel.Text = 'Analysis';
            app.DataPreviewLabel = uilabel(app.GridLayout2);
            app.DataPreviewLabel.Text = 'Data Preview';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.UIPreviewAxes = uiaxes(app.GridLayout4);
            app.DataItemsTable = uitable(app.GridLayout4);
            app.DataItemsTable.ColumnName = {''; 'Type'; 'Description'};
            app.DataItemsTable.RowName = {};
            app.Panel_3 = uipanel(app.GridLayout2);
            app.RemoveButton_2 = uibutton(app.Panel_3, 'push');
            app.RemoveButton_2.Text = 'Remove';
            app.CreateInstanceButton = uibutton(app.Panel_3, 'push');
            app.CreateInstanceButton.Text = 'Create Instance';
            app.ExportButton_2 = uibutton(app.Panel_3, 'push');
            app.ExportButton_2.Text = 'Export...';
            app.SetDefaultButton = uibutton(app.Panel_3, 'push');
            app.SetDefaultButton.Text = 'Set Default';
            app.contxtSaveAs = uicontextmenu(app.DVRamanToolUIFigure);
            app.SaveMenu_2 = uimenu(app.contxtSaveAs);
            app.SaveMenu_2.Text = 'Save';
            app.SaveAsMenu_2 = uimenu(app.contxtSaveAs);
            app.SaveAsMenu_2.Text = 'Save As ...';
            % Assign app.contxtSaveAs
            app.btnSaveMore.ContextMenu = app.contxtSaveAs;
            app.contxtDataMgrTreeNode = uicontextmenu(app.DVRamanToolUIFigure);
            app.contxtDataMgrTreeNode.ContextMenuOpeningFcn = createCallbackFcn(app, @contxtDataMgrTreeNodeContextMenuOpening, true);
            app.AddtoNewAnalysisMenu = uimenu(app.contxtDataMgrTreeNode);
            app.AddtoNewAnalysisMenu.Text = 'Add to New Analysis';
            app.AddtoMenu = uimenu(app.contxtDataMgrTreeNode);
            app.AddtoMenu.Text = 'Add to ...';
            app.emptyMenuPlaceholder = uimenu(app.contxtDataMgrTreeNode);
            app.emptyMenuPlaceholder.Text = 'Delete';
            app.contxtSubsetTreeNode = uicontextmenu(app.DVRamanToolUIFigure);
            app.contxtSubsetTreeNode.ContextMenuOpeningFcn = createCallbackFcn(app, @contxtSubsetTreeNodeContextMenuOpening, true);
            app.AssigntoNewGroupMenu = uimenu(app.contxtSubsetTreeNode);
            app.AssigntoNewGroupMenu.Text = 'Assign to New Group';
            app.AssigntoMenu = uimenu(app.contxtSubsetTreeNode);
            app.AssigntoMenu.Text = 'Assign to ...';
            app.RemoveMenu_2 = uimenu(app.contxtSubsetTreeNode);
            app.RemoveMenu_2.Text = 'Remove';
            % Show the figure after all components are created
            app.DVRamanToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ramatgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DVRamanToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DVRamanToolUIFigure)
        end
    end
end