classdef BgSubApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BaselineSubtractionUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        ShowDifferenceCheckBox          matlab.ui.control.CheckBox
        SameYAxisCheckBox               matlab.ui.control.CheckBox
        UpdatePreviewButton             matlab.ui.control.Button
        SubtractionMethodSettingsLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        ResettoDefaultsButton           matlab.ui.control.Button
        SubtractionMethodDropDown       matlab.ui.control.DropDown
        SubtractionMethodDropDownLabel  matlab.ui.control.Label
        SubtractonPreviewLabel          matlab.ui.control.Label
        PreviewSelectionLabel           matlab.ui.control.Label
        PreviewSelectionTree            matlab.ui.container.CheckBoxTree
        TabGroup                        matlab.ui.container.TabGroup
        ConstantTab                     matlab.ui.container.Tab
        bLabelbf                        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EditFieldLocMinEnd              matlab.ui.control.NumericEditField
        EditFieldLocMinStart            matlab.ui.control.NumericEditField
        LocalMinimumButton              matlab.ui.control.RadioButton
        GlobalMinimumButton             matlab.ui.control.RadioButton
        RegressionTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        QuantileValueEditField          matlab.ui.control.NumericEditField
        QuantileValueEditFieldLabel     matlab.ui.control.Label
        EstimationMethodDropDown        matlab.ui.control.DropDown
        EstimationMethodDropDownLabel   matlab.ui.control.Label
        RegressionMethodDropDown        matlab.ui.control.DropDown
        RegressionMethodDropDownLabel   matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        bLabelbf_2                      matlab.ui.control.Label
        PolynomialTab                   matlab.ui.container.Tab
        PLSTab                          matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GridLayout4_2                   matlab.ui.container.GridLayout
        AsymmetryEditField              matlab.ui.control.NumericEditField
        AsymmetryEditFieldLabel         matlab.ui.control.Label
        WeightExceptionEditField        matlab.ui.control.NumericEditField
        WeightExceptionEditFieldLabel   matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        LambdaEditField                 matlab.ui.control.NumericEditField
        LambdaEditFieldLabel            matlab.ui.control.Label
        bLabelbf_5                      matlab.ui.control.Label
        PreviewUIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Description
        DataObj % Description
    end
    
    methods (Access = public)
        
        function updateTree(app)
            %UPDATETREE Updates preview tree
            
            tree = app.PreviewSelectionTree;
            
            % Clear Tree
            a = tree.Children;
            a.delete;
            
            for i = 1:numel(app.DataObj)
                data = app.DataObj(i);
                datanode = uitreenode(tree, ...
                    "Text", data.DisplayName, ...   % Set Tree Text Label
                    "NodeData", data);     % Set Handle to DataContainer
            end
        end

        
        function updatePreview(app)
            % Update Preview
            
            % Get previewed object
            dataObj = getSelectedPreviewObj(app);
            if isempty(dataObj)
                return
            end
            
            original = dataObj.Data;
            preview = copy(original);
            
            % What baseline correction method?
            method = app.SubtractionMethodDropDown.Value;
            options = getMethodOptions(app, method);
            %params = namedargs2cell(options);
            
            switch method
                case "constant"
                    preview.removeBaseline(method, options);
                case "builtin"
                    preview.removeBaseline(method, options);
                case "airPLS"
                    preview.removeBaseline(method, options);
                otherwise
                    return
            end

            xdatori = original.Graph;
            ydatori = original.FlatDataArray;

            xdatnew = preview.Graph;
            ydatnew = preview.FlatDataArray;

            ydiff = ydatori - ydatnew;
            
            % Get axes handles
            ax = app.PreviewUIAxes;
            cla(ax, 'reset');
            
            if app.SameYAxisCheckBox.Value
                % Single Y Axis
                hold(ax, 'on');

                plot(ax, xdatori, ydatori);
                
                if app.ShowDifferenceCheckBox.Value
                    plot(ax, xdatori, ydiff);
                end

                plot(ax, xdatnew, ydatnew);
             
                hold(ax, 'off');
            else
                % Double Y Axes
                            
                % Plot original
                yyaxis(ax, 'left');
                plot(ax, xdatori, ydatori);

                if app.ShowDifferenceCheckBox.Value
                    hold(ax, 'on');
                    plot(ax, xdatori, ydiff);
                    hold(ax, 'off');
                end
                
                % Plot baseline-corrected preview
                yyaxis(ax, 'right');
                plot(ax, xdatnew, ydatnew);
            end
                
        end
        
        function data = getSelectedPreviewObj(app)
            
            tree = app.PreviewSelectionTree;
            
            data = [];
            
            if isempty(tree.Children) || isempty(tree.SelectedNodes)
                % Nothing to be previewed, data tree is empty
                return
            end
            
            node = tree.SelectedNodes;
            
            if numel(node) > 1
                % Can only preview one data object
                return
            end
            
            data = node.NodeData;
        end
        
        function options = getMethodOptions(app, method)
            % GETMETHODOPTIONS Get parameters of selected method

            options = [];
            
            switch method
                case "constant"
                    options.local = app.LocalMinimumButton.Value;
                    options.local_start = app.EditFieldLocMinStart.Value;
                    options.local_end = app.EditFieldLocMinEnd.Value;
                case "builtin"
                    options.WindowSize = app.WindowSizeEditField.Value;
                    options.StepSize = app.StepSizeEditField.Value;
                    options.RegressionMethod = app.RegressionMethodDropDown.Value;
                    options.EstimationMethod = app.EstimationMethodDropDown.Value;
                    options.QuantileValue = app.QuantileValueEditField.Value;
                case "airPLS"
                    options.pls_asymmetry = app.AsymmetryEditField.Value;
                    options.pls_weight_exception = app.WeightExceptionEditField.Value;
                    options.pls_max_iterations = app.MaxIterationsEditField.Value;
                    options.pls_order = app.OrderEditField.Value;
                    options.pls_lambda = app.LambdaEditField.Value;
            end
            
        end
        
        function apply_subtraction(app)
            
            % Check whether data is empty
            if isempty(app.DataObj)
                return
            end

            tree = app.PreviewSelectionTree;

            % Check whether any data is checked
            if isempty(tree.CheckedNodes)
                return
            end

            % Get method and options
            method = app.SubtractionMethodDropDown.Value;
            options = getMethodOptions(app, method);

            % Apply on all checked nodes
            for i=1:numel(tree.CheckedNodes)
                tree.CheckedNodes(i).NodeData.Data.removeBaseline(method, options);
            end

            % Close the app
            delete(app);
        end
    end
    
    methods (Access = private)
        
        function update_apply_button(app)
            % UPDATE_APPLY_BUTTON
            
            tree = app.PreviewSelectionTree;
            
            % Only enable apply button when data is checked
            if isempty(app.DataObj) || isempty(tree.CheckedNodes)
                app.ApplyButton.Enable = false;
            else
                app.ApplyButton.Enable = true;
            end

            % Update text
            app.ApplyButton.Text = sprintf('Apply (%d)', numel(tree.CheckedNodes));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, options)
            % STARTUPFCN
            
            arguments
                app BgSubApp;
                mainapp ramatguiapp;
                options.DataObjects DataContainer = []; % Provided DataObjects for baseline subtractions
            end
            
            % Set caller (main) app
            app.MainApp = mainapp;
            
            if isempty(options.DataObjects)
                % No Data provided, retrieve from selection
                data = vertcat( app.DataMgrTree.SelectedNodes.NodeData );
            else
                data = options.DataObjects;
            end
            
            % Store data object handles
            app.DataObj = data;
            
            % Update Tree
            app.updateTree();

            % Check all tree nodes by default
            tree = app.PreviewSelectionTree;
            tree.CheckedNodes = tree.Children;

            app.update_apply_button();
            
        end

        % Value changed function: SubtractionMethodDropDown
        function SubtractionMethodDropDownValueChanged(app, event)
            value = app.SubtractionMethodDropDown.Value;
            
            updatePreview(app);
        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            updatePreview(app);
        end

        % Selection changed function: PreviewSelectionTree
        function PreviewSelectionTreeSelectionChanged(app, event)
            selectedNodes = app.PreviewSelectionTree.SelectedNodes;
            
            updatePreview(app);
        end

        % Value changed function: ShowDifferenceCheckBox
        function ShowDifferenceCheckBoxValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: SameYAxisCheckBox
        function SameYAxisCheckBoxValueChanged(app, event)
            app.updatePreview();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.apply_subtraction();
        end

        % Callback function: PreviewSelectionTree
        function PreviewSelectionTreeCheckedNodesChanged(app, event)
            app.update_apply_button();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BaselineSubtractionUIFigure = uifigure('Visible', 'off');
            app.BaselineSubtractionUIFigure.Name = 'Baseline Subtraction';
            app.GridLayout = uigridlayout(app.BaselineSubtractionUIFigure);
            app.PreviewUIAxes = uiaxes(app.GridLayout);
            title(app.PreviewUIAxes, 'Title')
            xlabel(app.PreviewUIAxes, 'X')
            ylabel(app.PreviewUIAxes, 'Y')
            zlabel(app.PreviewUIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConstantTab = uitab(app.TabGroup);
            app.ConstantTab.Title = 'Constant';
            app.ButtonGroup = uibuttongroup(app.ConstantTab);
            app.GlobalMinimumButton = uiradiobutton(app.ButtonGroup);
            app.GlobalMinimumButton.Text = 'Global Minimum';
            app.GlobalMinimumButton.Value = true;
            app.LocalMinimumButton = uiradiobutton(app.ButtonGroup);
            app.LocalMinimumButton.Text = 'Local Minimum:';
            app.EditFieldLocMinStart = uieditfield(app.ButtonGroup, 'numeric');
            app.EditFieldLocMinEnd = uieditfield(app.ButtonGroup, 'numeric');
            app.EditFieldLocMinEnd.Value = 1;
            app.bLabelbf = uilabel(app.ConstantTab);
            app.bLabelbf.Interpreter = 'html';
            app.bLabelbf.Text = {'<p><b>Constant Value Subtraction</b></p>'; '<p>Subtracts a constant value from entire spectrum</p>'};
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.GridLayout3 = uigridlayout(app.RegressionTab);
            app.bLabelbf_2 = uilabel(app.GridLayout3);
            app.bLabelbf_2.Interpreter = 'html';
            app.bLabelbf_2.Text = {'<p><b>MATLAB Built-In Regression</b></p>'; '<p>...</p>'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.WindowSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WindowSizeEditField.Limits = [0 Inf];
            app.WindowSizeEditField.Value = 200;
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.StepSizeEditField.Value = 200;
            app.RegressionMethodDropDownLabel = uilabel(app.GridLayout4);
            app.RegressionMethodDropDownLabel.Text = 'Regression Method';
            app.RegressionMethodDropDown = uidropdown(app.GridLayout4);
            app.RegressionMethodDropDown.Items = {'Piecewise Cubic', 'Linear', 'Spline'};
            app.RegressionMethodDropDown.ItemsData = {'pchip', 'linear', 'spline'};
            app.RegressionMethodDropDown.Value = 'pchip';
            app.EstimationMethodDropDownLabel = uilabel(app.GridLayout4);
            app.EstimationMethodDropDownLabel.Text = 'Estimation Method';
            app.EstimationMethodDropDown = uidropdown(app.GridLayout4);
            app.EstimationMethodDropDown.Items = {'Quantile', 'Expectation-Maximization'};
            app.EstimationMethodDropDown.ItemsData = {'quantile', 'em'};
            app.EstimationMethodDropDown.Value = 'quantile';
            app.QuantileValueEditFieldLabel = uilabel(app.GridLayout4);
            app.QuantileValueEditFieldLabel.Text = 'Quantile Value';
            app.QuantileValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.QuantileValueEditField.Limits = [0 1];
            app.QuantileValueEditField.Value = 0.1;
            app.PolynomialTab = uitab(app.TabGroup);
            app.PolynomialTab.Title = 'Polynomial';
            app.PLSTab = uitab(app.TabGroup);
            app.PLSTab.Title = 'PLS';
            app.GridLayout3_2 = uigridlayout(app.PLSTab);
            app.bLabelbf_5 = uilabel(app.GridLayout3_2);
            app.bLabelbf_5.Interpreter = 'html';
            app.bLabelbf_5.Text = {'<p><b>Penalised Least Squares</b></p>'; '<p>Baseline correction using adaptive iteratively reweighted Penalized Least Squares</p>'};
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.LambdaEditFieldLabel = uilabel(app.GridLayout4_2);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.LambdaEditField.Limits = [0 Inf];
            app.LambdaEditField.Value = 100000000;
            app.OrderEditFieldLabel = uilabel(app.GridLayout4_2);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.OrderEditField.Limits = [1 9];
            app.OrderEditField.Value = 2;
            app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout4_2);
            app.MaxIterationsEditFieldLabel.Text = 'Max. Iterations';
            app.MaxIterationsEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.MaxIterationsEditField.Limits = [0 9999];
            app.MaxIterationsEditField.Value = 20;
            app.WeightExceptionEditFieldLabel = uilabel(app.GridLayout4_2);
            app.WeightExceptionEditFieldLabel.Text = 'Weight Exception';
            app.WeightExceptionEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.WeightExceptionEditField.Limits = [0 1];
            app.WeightExceptionEditField.Value = 0.1;
            app.AsymmetryEditFieldLabel = uilabel(app.GridLayout4_2);
            app.AsymmetryEditFieldLabel.Text = 'Asymmetry';
            app.AsymmetryEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.AsymmetryEditField.Limits = [0 1];
            app.AsymmetryEditField.Value = 0.05;
            app.PreviewSelectionTree = uitree(app.GridLayout, 'checkbox');
            app.PreviewSelectionTree.SelectionChangedFcn = createCallbackFcn(app, @PreviewSelectionTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.PreviewSelectionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PreviewSelectionTreeCheckedNodesChanged, true);
            app.PreviewSelectionLabel = uilabel(app.GridLayout);
            app.PreviewSelectionLabel.Text = 'Preview Selection:';
            app.SubtractonPreviewLabel = uilabel(app.GridLayout);
            app.SubtractonPreviewLabel.Text = 'Subtracton Preview:';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SubtractionMethodDropDownLabel = uilabel(app.GridLayout2);
            app.SubtractionMethodDropDownLabel.Text = 'Subtraction Method:';
            app.SubtractionMethodDropDown = uidropdown(app.GridLayout2);
            app.SubtractionMethodDropDown.Items = {'Constant', 'Regression', 'Polynomial', 'PLS'};
            app.SubtractionMethodDropDown.ItemsData = {'constant', 'builtin', 'polynomial', 'airPLS'};
            app.SubtractionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtractionMethodDropDownValueChanged, true);
            app.SubtractionMethodDropDown.Value = 'constant';
            app.ResettoDefaultsButton = uibutton(app.GridLayout2, 'push');
            app.ResettoDefaultsButton.Text = 'Reset to Defaults';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SubtractionMethodSettingsLabel = uilabel(app.GridLayout);
            app.SubtractionMethodSettingsLabel.Text = 'Subtraction Method Settings:';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UpdatePreviewButton = uibutton(app.GridLayout5, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.SameYAxisCheckBox = uicheckbox(app.GridLayout5);
            app.SameYAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameYAxisCheckBoxValueChanged, true);
            app.SameYAxisCheckBox.Text = 'Same Y-Axis';
            app.ShowDifferenceCheckBox = uicheckbox(app.GridLayout5);
            app.ShowDifferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDifferenceCheckBoxValueChanged, true);
            app.ShowDifferenceCheckBox.Text = 'Show Difference';
            app.ShowDifferenceCheckBox.Value = true;
            % Show the figure after all components are created
            app.BaselineSubtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BgSubApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BaselineSubtractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BaselineSubtractionUIFigure)
        end
    end
end