classdef SpectralPlotEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        ExportasButton          matlab.ui.control.Button
        SavePlotButton          matlab.ui.control.Button
        GeneratePlotButton      matlab.ui.control.Button
        AutoUpdateCheckBox      matlab.ui.control.CheckBox
        UpdatePreviewButton     matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        StackingPanel           matlab.ui.container.Panel
        StackingDistanceSlider  matlab.ui.control.Slider
        DistanceSliderLabel     matlab.ui.control.Label
        StackedPlotCheckBox     matlab.ui.control.CheckBox
        PlotOptionsPanel        matlab.ui.container.Panel
        TitleEditField          matlab.ui.control.EditField
        TitleEditFieldLabel     matlab.ui.control.Label
        CollateGroupsCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Plot; % SpecPlot
    end
    
    methods (Access = public)
        
        function generate_plot(app, preview)
            %GENERATE_PLOT Update spectral plot preview

            arguments
                app;
                preview = true;
            end
            
            if isempty(app.Plot)
                fprintf('No Data');
                return
            end
            
            plt = app.Plot;

            % When preview, target = UIAxes
            if preview
                target = app.UIAxes;
            else
                target = []; % empty target = create new figure
            end
            
            % Call plot method, target = UIAxes
            plt.Data.plot( ...
                Axes=target, ...
                PlotStackDistance=plt.PlotOptions.PlotStackDistance, ...
                PlotType=plt.PlotOptions.PlotType);
            
        end
        
        function getplotoptions(app)
            %GETPLOTOPTIONS Gets PlotOptions und updates GUI components
            
            % Set StackDistance slider
            app.StackingDistanceSlider.Value = app.Plot.PlotOptions.PlotStackDistance;
            
            % Set PlotType checkbox
            switch app.Plot.PlotOptions.PlotType
                case "Stacked"
                    app.StackedPlotCheckBox.Value = true;
                case "Overlaid"
                    app.StackedPlotCheckBox.Value = false;
                otherwise
                    fprintf('Illegal PlotType');
            end
            
%             % Set Name edit field
%             app.TitleEditField.Value = app.Plot.Name;

            
        end
        
        function autoupdate(app)
            %AUTOUPDATE Wrapper for generate_plot(), respecting
            %AutoUpdate property
            
            if app.AutoUpdateCheckBox.Value
                generate_plot(app, true);
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data, kwargs)
            arguments
                app;
                Data = SpecPlot.empty();
                kwargs.GroupNames;
                kwargs.GroupSizes;
                kwargs.DataSizes;
                kwargs.PlotType = 'Overlaid';
            end
            
            if isempty(Data)
                fprintf('Loaded empty data.\n');
            end
            
            switch class(Data)
                case 'SpecPlot'
                    % SpecPlot Instance provided, load in SpecPlot
                    specplot = Data;
                    
                case 'DataContainer'
                    % Loose input provided, store in SpecPlot instance
                    specplot = SpecPlot(Data, ...
                        GroupNames=kwargs.GroupNames, ...
                        GroupSizes=kwargs.GroupSizes, ...
                        DataSizes=kwargs.DataSizes, ...
                        PlotType=kwargs.PlotType);
                    
            end
                        
            % Store SpecPlot
            app.Plot = specplot;
            
            % Update GUI components
            getplotoptions(app);
            
            % Update Plot preview
            generate_plot(app);
            
        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            %UPDATEPREVIEWBUTTONPUSHED Callback
            
            generate_plot(app, true);
            
        end

        % Value changed function: StackedPlotCheckBox
        function StackedPlotCheckBoxValueChanged(app, event)
            value = app.StackedPlotCheckBox.Value;
            
            if value == true
                app.Plot.PlotOptions.PlotType = "Stacked";
            else
                app.Plot.PlotOptions.PlotType = "Overlaid";
            end
            
            autoupdate(app);
            
        end

        % Value changed function: StackingDistanceSlider
        function StackingDistanceSliderValueChanged(app, event)
            value = app.StackingDistanceSlider.Value;
            
            app.Plot.PlotOptions.PlotStackDistance = value;
            
            autoupdate(app);
            
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            
            app.Plot.Name = value;
            
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            app.generate_plot(false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.PlotOptionsPanel = uipanel(app.Panel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.CollateGroupsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.CollateGroupsCheckBox.Text = 'Collate Groups';
            app.TitleEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.StackingPanel = uipanel(app.Panel);
            app.StackingPanel.Title = 'Stacking';
            app.StackedPlotCheckBox = uicheckbox(app.StackingPanel);
            app.StackedPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StackedPlotCheckBoxValueChanged, true);
            app.StackedPlotCheckBox.Text = 'Stacked Plot';
            app.DistanceSliderLabel = uilabel(app.StackingPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.StackingDistanceSlider = uislider(app.StackingPanel);
            app.StackingDistanceSlider.Limits = [0 2];
            app.StackingDistanceSlider.MajorTicks = [0 1 2];
            app.StackingDistanceSlider.MajorTickLabels = {'min', 'default', 'max'};
            app.StackingDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @StackingDistanceSliderValueChanged, true);
            app.StackingDistanceSlider.MinorTicks = [];
            app.StackingDistanceSlider.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''; 'Name'; 'Size'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UpdatePreviewButton = uibutton(app.GridLayout3, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout3);
            app.AutoUpdateCheckBox.Text = 'Auto Update';
            app.AutoUpdateCheckBox.Value = true;
            app.GeneratePlotButton = uibutton(app.GridLayout3, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.SavePlotButton = uibutton(app.GridLayout3, 'push');
            app.SavePlotButton.Text = 'Save Plot';
            app.ExportasButton = uibutton(app.GridLayout3, 'push');
            app.ExportasButton.Text = 'Export as ...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralPlotEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end